classdef CheckChannelPower < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ExportBadChannelsButton   matlab.ui.control.Button
        BadChannelsListBox        matlab.ui.control.ListBox
        BadChannelsListBoxLabel   matlab.ui.control.Label
        GoodChannelsListBox       matlab.ui.control.ListBox
        GoodChannelsListBoxLabel  matlab.ui.control.Label
        BadChannelButton          matlab.ui.control.Button
        NextButton                matlab.ui.control.Button
        PreviousButton            matlab.ui.control.Button
        LogPower                  matlab.ui.control.UIAxes
        DataDist                  matlab.ui.control.UIAxes
        TimeCourse                matlab.ui.control.UIAxes
        Power                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Data
        PowerData
        LogPowerData
        SamplingFreq
        xLabelPower
        ChannelLabels
        BadChannels
        Index
    end
    
    methods (Access = private)
        
        
        function refreshChannel(app)
            app.Power.Children.YData = app.PowerData(:,app.Index);
            title(app.Power,app.ChannelLabels{app.Index})
            app.LogPower.Children.YData = app.LogPowerData(:,app.Index);
            title(app.LogPower,app.ChannelLabels{app.Index})
            app.TimeCourse.Children.YData = app.Data(:,app.Index);
            
            histogram(app.DataDist,app.Data(:,app.Index))
            app.DataDist.XLim = [-max(abs(app.DataDist.Children.BinEdges)) max(abs(app.DataDist.Children.BinEdges))];

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataStructure)
            app.Data = dataStructure.Data;
            app.SamplingFreq = dataStructure.SamplingFreq;
            app.xLabelPower = 0:round(app.SamplingFreq/2);
            app.ChannelLabels = dataStructure.Labels;
            test = find(size(app.Data)==length(app.ChannelLabels));
            if test~=2
                app.Data = app.Data';
            end
            
            app.PowerData = log10(pwelch(app.Data,[],[],app.SamplingFreq));
            app.LogPowerData = real(log10(app.PowerData));
            app.BadChannels = false(length(app.ChannelLabels),1);
            app.GoodChannelsListBox.Items = app.ChannelLabels;
            app.GoodChannelsListBox.ItemsData = 1:length(app.ChannelLabels);
            app.Index = 1;
            
            plot(app.Power,app.xLabelPower,app.PowerData(:,app.Index))
            app.Power.YLim = [floor(min(app.PowerData(:))),ceil(max(app.PowerData(:)))];
            ylabel(app.Power,'Power/frequency (dB/Hz)')
            xlabel(app.Power,'Frequency (Hz)')
            title(app.Power,app.GoodChannelsListBox.Items{app.Index})
            
            plot(app.LogPower,app.xLabelPower,app.LogPowerData(:,app.Index))
%             app.LogPower.YLim = [floor(min(app.LogPowerData(:))),ceil(max(app.LogPowerData(:)))];
            ylabel(app.LogPower,'LogPower/frequency')
            xlabel(app.LogPower,'Frequency (Hz)')
            title(app.LogPower,app.GoodChannelsListBox.Items{app.Index})
            
            plot(app.TimeCourse,app.Data(:,app.Index))
            xlabel(app.TimeCourse, 'Time (samples)')
            ylabel(app.TimeCourse, '\muV')
            title(app.TimeCourse,'Signal Trace')
            
            histogram(app.DataDist,app.Data(:,app.Index))
            app.DataDist.XLim = [-max(abs(app.DataDist.Children.BinEdges)) max(abs(app.DataDist.Children.BinEdges))];
            title(app.DataDist,'Histogram')
            
        end

        % Button pushed function: BadChannelButton
        function BadChannelButtonPushed(app, event)
            if isempty(app.GoodChannelsListBox.Value)
                return
            end
            ind = find(app.GoodChannelsListBox.ItemsData==app.GoodChannelsListBox.Value);
            app.BadChannelsListBox.Items = [app.BadChannelsListBox.Items app.GoodChannelsListBox.Items{ind}];
            app.BadChannelsListBox.ItemsData = [app.BadChannelsListBox.ItemsData app.GoodChannelsListBox.ItemsData(ind)];
            app.GoodChannelsListBox.Items(ind) = [];
            app.GoodChannelsListBox.ItemsData(ind) = [];
            if ind>1
                app.GoodChannelsListBox.Value = app.GoodChannelsListBox.ItemsData(ind-1);
            else
                app.GoodChannelsListBox.Value = app.GoodChannelsListBox(1);
            end
            app.Index = app.GoodChannelsListBox.Value;
            refreshChannel(app)
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if ~isempty(app.GoodChannelsListBox.Value)
                ind = find(app.GoodChannelsListBox.ItemsData==app.GoodChannelsListBox.Value)-1;
                if ind>=1
                    app.GoodChannelsListBox.Value = app.GoodChannelsListBox.ItemsData(ind);
                    app.Index = app.GoodChannelsListBox.Value;
                end
            elseif ~isempty(app.BadChannelsListBox.Value)
                ind = find(app.BadChannelsListBox.ItemsData==app.BadChannelsListBox.Value)-1;
                if ind>=1
                    app.BadChannelsListBox.Value = app.BadChannelsListBox.ItemsData(ind);
                    app.Index = app.BadChannelsListBox.Value;
                end
            else
                app.GoodChannelListBox.Value = app.GoodChannelsListBox.ItemsData(1);
            end
            refreshChannel(app)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if ~isempty(app.GoodChannelsListBox.Value)
                ind = find(app.GoodChannelsListBox.ItemsData==app.GoodChannelsListBox.Value)+1;
                if ind<=length(app.GoodChannelsListBox.ItemsData)
                    app.GoodChannelsListBox.Value = app.GoodChannelsListBox.ItemsData(ind);
                    app.Index = app.GoodChannelsListBox.Value;
                end
            elseif ~isempty(app.BadChannelsListBox.Value)
                ind = find(app.BadChannelsListBox.ItemsData==app.BadChannelsListBox.Value)+1;
                if ind<=length(app.BadChannelsListBox.ItemsData)
                    app.BadChannelsListBox.Value = app.BadChannelsListBox.ItemsData(ind);
                    app.Index = app.BadChannelsListBox.Value;
                end
            end
            refreshChannel(app)
        end

        % Value changed function: GoodChannelsListBox
        function GoodChannelsListBoxValueChanged(app, event)
            app.BadChannelsListBox.Value = {};
            app.Index = app.GoodChannelsListBox.Value;
            refreshChannel(app)
        end

        % Value changed function: BadChannelsListBox
        function BadChannelsListBoxValueChanged(app, event)
            app.GoodChannelsListBox.Value = {};
            app.Index = app.BadChannelsListBox.Value;
            refreshChannel(app)
        end

        % Button pushed function: ExportBadChannelsButton
        function ExportBadChannelsButtonPushed(app, event)
            assignin('base','BadChannels',sort(app.BadChannelsListBox.ItemsData))
            assignin('base','BadChannelLabels',app.BadChannelsListBox.Items)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Power = uiaxes(app.UIFigure);
            title(app.Power, 'Title')
            xlabel(app.Power, 'X')
            ylabel(app.Power, 'Y')
            app.TimeCourse = uiaxes(app.UIFigure);
            title(app.TimeCourse, 'Title')
            xlabel(app.TimeCourse, 'X')
            ylabel(app.TimeCourse, 'Y')
            app.DataDist = uiaxes(app.UIFigure);
            title(app.DataDist, 'Title')
            xlabel(app.DataDist, 'X')
            ylabel(app.DataDist, 'Y')
            app.LogPower = uiaxes(app.UIFigure);
            title(app.LogPower, 'Title')
            xlabel(app.LogPower, 'X')
            ylabel(app.LogPower, 'Y')
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.BadChannelButton = uibutton(app.UIFigure, 'push');
            app.BadChannelButton.ButtonPushedFcn = createCallbackFcn(app, @BadChannelButtonPushed, true);
            app.BadChannelButton.Text = 'Bad Channel';
            app.GoodChannelsListBoxLabel = uilabel(app.UIFigure);
            app.GoodChannelsListBoxLabel.Text = 'Good Channels';
            app.GoodChannelsListBox = uilistbox(app.UIFigure);
            app.GoodChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @GoodChannelsListBoxValueChanged, true);
            app.BadChannelsListBoxLabel = uilabel(app.UIFigure);
            app.BadChannelsListBoxLabel.Text = 'Bad Channels';
            app.BadChannelsListBox = uilistbox(app.UIFigure);
            app.BadChannelsListBox.Items = {};
            app.BadChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @BadChannelsListBoxValueChanged, true);
            app.BadChannelsListBox.Value = {};
            app.ExportBadChannelsButton = uibutton(app.UIFigure, 'push');
            app.ExportBadChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportBadChannelsButtonPushed, true);
            app.ExportBadChannelsButton.Text = 'Export Bad Channels';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CheckChannelPower(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end