classdef CheckChannelPowerMod1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        Power                     matlab.ui.control.UIAxes
        TimeCourse                matlab.ui.control.UIAxes
        MiddlePanel               matlab.ui.container.Panel
        LogPower                  matlab.ui.control.UIAxes
        DataDist                  matlab.ui.control.UIAxes
        RightPanel                matlab.ui.container.Panel
        PreviousButton            matlab.ui.control.Button
        BadChannelButton          matlab.ui.control.Button
        GoodChannelsListBoxLabel  matlab.ui.control.Label
        GoodChannelsListBox       matlab.ui.control.ListBox
        NextButton                matlab.ui.control.Button
        ExportBadChannelsButton   matlab.ui.control.Button
        BadChannelsListBoxLabel   matlab.ui.control.Label
        BadChannelsListBox        matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        Data
        PowerData
        LogPowerData
        SamplingFreq
        xLabel
        ChannelLabels
        BadChannels
        Index    
    end
    
    methods (Access = private)
        
        function refreshChannel(app)
            app.Power.Children.YData = app.PowerData(:,app.Index);
            title(app.Power,app.ChannelLabels{app.Index})
            app.LogPower.Children.YData = app.LogPowerData(:,app.Index);
            title(app.LogPower,app.ChannelLabels{app.Index})
            app.TimeCourse.Children.YData = app.Data(:,app.Index);
            
            histogram(app.DataDist,app.Data(:,app.Index))
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Data = dataStructure.data;
            app.SamplingFreq = dataStructure.samplingFreq;
            app.xLabel = dataStructure.xAxis;
            app.ChannelLabels = dataStructure.labels;
            test = find(size(app.Data)==length(app.ChannelLabels));
            if test~=2
                app.Data = app.Data';
            end
            
            app.PowerData = log10(pwelch(app.Data,[],[],app.SamplingFreq));
            app.LogPowerData = real(log10(app.PowerData));
            app.BadChannels = false(length(app.ChannelLabels),1);
            app.GoodChannelsListBox.Items = app.ChannelLabels;
            app.GoodChannelsListBox.ItemsData = 1:length(app.ChannelLabels);
            app.Index = 1;
            
            plot(app.Power,app.xLabel,app.PowerData(:,app.Index))
            app.Power.YLim = [floor(min(app.PowerData(:))),ceil(max(app.PowerData(:)))];
            ylabel(app.Power,'Power/frequency (dB/Hz)')
            xlabel(app.Power,'Frequency (Hz)')
            title(app.Power,app.GoodChannelsListBox.Items{app.Index})
            
            plot(app.LogPower,app.xLabel,app.LogPowerData(:,app.Index))
%             app.LogPower.YLim = [floor(min(app.LogPowerData(:))),ceil(max(app.LogPowerData(:)))];
            ylabel(app.LogPower,'LogPower/frequency')
            xlabel(app.LogPower,'Frequency (Hz)')
            title(app.LogPower,app.GoodChannelsListBox.Items{app.Index})
            
            plot(app.TimeCourse,app.Data(:,app.Index))
            xlabel(app.TimeCourse, 'Time (samples)')
            ylabel(app.TimeCourse, '\muV')
            
            histogram(app.DataDist,app.Data(:,app.Index))
        end

        % Button pushed function: BadChannelButton
        function BadChannelButtonPushed(app, event)
            if isempty(app.GoodChannelsListBox.Value)
                return
            end
            ind = find(app.GoodChannelsListBox.ItemsData==app.GoodChannelsListBox.Value);
            app.BadChannelsListBox.Items = [app.BadChannelsListBox.Items app.GoodChannelsListBox.Items{ind}];
            app.BadChannelsListBox.ItemsData = [app.BadChannelsListBox.ItemsData app.GoodChannelsListBox.ItemsData(ind)];
            app.GoodChannelsListBox.Items(ind) = [];
            app.GoodChannelsListBox.ItemsData(ind) = [];
            if ind>1
                app.GoodChannelsListBox.Value = app.GoodChannelsListBox.ItemsData(ind-1);
            else
                app.GoodChannelsListBox.Value = app.GoodChannelsListBox(1);
            end
            app.Index = app.GoodChannelsListBox.Value;
            refreshChannel(app)
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if ~isempty(app.GoodChannelsListBox.Value)
                ind = find(app.GoodChannelsListBox.ItemsData==app.GoodChannelsListBox.Value)-1;
                if ind>=1
                    app.GoodChannelsListBox.Value = app.GoodChannelsListBox.ItemsData(ind);
                    app.Index = app.GoodChannelsListBox.Value;
                end
            elseif ~isempty(app.BadChannelsListBox.Value)
                ind = find(app.BadChannelsListBox.ItemsData==app.BadChannelsListBox.Value)-1;
                if ind>=1
                    app.BadChannelsListBox.Value = app.BadChannelsListBox.ItemsData(ind);
                    app.Index = app.BadChannelsListBox.Value;
                end
            else
                app.GoodChannelListBox.Value = app.GoodChannelsListBox.ItemsData(1);
            end
            refreshChannel(app)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if ~isempty(app.GoodChannelsListBox.Value)
                ind = find(app.GoodChannelsListBox.ItemsData==app.GoodChannelsListBox.Value)+1;
                if ind<=length(app.GoodChannelsListBox.ItemsData)
                    app.GoodChannelsListBox.Value = app.GoodChannelsListBox.ItemsData(ind);
                    app.Index = app.GoodChannelsListBox.Value;
                end
            elseif ~isempty(app.BadChannelsListBox.Value)
                ind = find(app.BadChannelsListBox.ItemsData==app.BadChannelsListBox.Value)+1;
                if ind<=length(app.BadChannelsListBox.ItemsData)
                    app.BadChannelsListBox.Value = app.BadChannelsListBox.ItemsData(ind);
                    app.Index = app.BadChannelsListBox.Value;
                end
            end
            refreshChannel(app)
        end

        % Value changed function: GoodChannelsListBox
        function GoodChannelsListBoxValueChanged(app, event)
            app.BadChannelsListBox.Value = {};
            app.Index = app.GoodChannelsListBox.Value;
            refreshChannel(app)
        end

        % Value changed function: BadChannelsListBox
        function BadChannelsListBoxValueChanged(app, event)
            app.GoodChannelsListBox.Value = {};
            app.Index = app.BadChannelsListBox.Value;
            refreshChannel(app)
        end

        % Button pushed function: ExportBadChannelsButton
        function ExportBadChannelsButtonPushed(app, event)
            assignin('base','BadChannels',app.BadChannelsListBox.ItemsData)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Power = uiaxes(app.LeftPanel);
            title(app.Power, 'Title')
            xlabel(app.Power, 'X')
            ylabel(app.Power, 'Y')
            app.TimeCourse = uiaxes(app.LeftPanel);
            title(app.TimeCourse, 'Title')
            xlabel(app.TimeCourse, 'X')
            ylabel(app.TimeCourse, 'Y')
            app.MiddlePanel = uipanel(app.GridLayout);
            app.LogPower = uiaxes(app.MiddlePanel);
            title(app.LogPower, 'Title')
            xlabel(app.LogPower, 'X')
            ylabel(app.LogPower, 'Y')
            app.DataDist = uiaxes(app.MiddlePanel);
            title(app.DataDist, 'Title')
            xlabel(app.DataDist, 'X')
            ylabel(app.DataDist, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.PreviousButton = uibutton(app.RightPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.BadChannelButton = uibutton(app.RightPanel, 'push');
            app.BadChannelButton.ButtonPushedFcn = createCallbackFcn(app, @BadChannelButtonPushed, true);
            app.BadChannelButton.Text = 'Bad Channel';
            app.GoodChannelsListBoxLabel = uilabel(app.RightPanel);
            app.GoodChannelsListBoxLabel.Text = 'Good Channels';
            app.GoodChannelsListBox = uilistbox(app.RightPanel);
            app.GoodChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @GoodChannelsListBoxValueChanged, true);
            app.NextButton = uibutton(app.RightPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ExportBadChannelsButton = uibutton(app.RightPanel, 'push');
            app.ExportBadChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportBadChannelsButtonPushed, true);
            app.ExportBadChannelsButton.Text = 'Export Bad Channels';
            app.BadChannelsListBoxLabel = uilabel(app.RightPanel);
            app.BadChannelsListBoxLabel.Text = 'Bad Channels';
            app.BadChannelsListBox = uilistbox(app.RightPanel);
            app.BadChannelsListBox.Items = {};
            app.BadChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @BadChannelsListBoxValueChanged, true);
            app.BadChannelsListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CheckChannelPowerMod1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end